classdef ImagingPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        COMResetMenu                matlab.ui.container.Menu
        COMReinitMenu               matlab.ui.container.Menu
        XYStagePanel                matlab.ui.container.Panel
        UpButton                    matlab.ui.control.Button
        RightButton                 matlab.ui.control.Button
        LeftButton                  matlab.ui.control.Button
        DownButton                  matlab.ui.control.Button
        DistancemmEditFieldLabel    matlab.ui.control.Label
        DistancemmEditField         matlab.ui.control.NumericEditField
        TabGroup                    matlab.ui.container.TabGroup
        ImageTab                    matlab.ui.container.Tab
        SaveImageButton             matlab.ui.control.Button
        SaveDirEditFieldLabel       matlab.ui.control.Label
        SaveDirEditField            matlab.ui.control.EditField
        BrowseButton                matlab.ui.control.Button
        FileNameEditFieldLabel      matlab.ui.control.Label
        FileNameEditField           matlab.ui.control.EditField
        ImageFormatDropDownLabel    matlab.ui.control.Label
        ImageFormatDropDown         matlab.ui.control.DropDown
        TextArea                    matlab.ui.control.TextArea
        VideoTab                    matlab.ui.container.Tab
        SaveVideoButton             matlab.ui.control.Button
        BrowseButton_2              matlab.ui.control.Button
        TextArea_2                  matlab.ui.control.TextArea
        SaveDirEditField_2Label     matlab.ui.control.Label
        SaveDirEditField_2          matlab.ui.control.EditField
        FileNameEditField_2Label    matlab.ui.control.Label
        FileNameEditField_2         matlab.ui.control.EditField
        ImageFormatDropDown_2Label  matlab.ui.control.Label
        ImageFormatDropDown_2       matlab.ui.control.DropDown
        MotorTab                    matlab.ui.container.Tab
        RecenterPlatformButton      matlab.ui.control.Button
        InvertTopBottomCheckBox     matlab.ui.control.CheckBox
        InvertLeftRightCheckBox     matlab.ui.control.CheckBox
        PreviewTab                  matlab.ui.container.Tab
        OpenPreviewWindowButton     matlab.ui.control.Button
        TabGroup2                   matlab.ui.container.TabGroup
        WhiteLEDsTab                matlab.ui.container.Tab
        SetWhiteLEDsButton          matlab.ui.control.Button
        IntensityKnobLabel_2        matlab.ui.control.Label
        WhiteIntensityKnob          matlab.ui.control.Knob
        ValueSpinnerLabel_2         matlab.ui.control.Label
        WhiteValueSpinner           matlab.ui.control.Spinner
        BlueLEDsTab                 matlab.ui.container.Tab
        IntensityKnobLabel          matlab.ui.control.Label
        BlueIntensityKnob           matlab.ui.control.Knob
        TopBlueCheckBox             matlab.ui.control.CheckBox
        LeftBlueCheckBox            matlab.ui.control.CheckBox
        BottomBlueCheckBox          matlab.ui.control.CheckBox
        RightBlueCheckBox           matlab.ui.control.CheckBox
        SetBlueLEDsButton           matlab.ui.control.Button
        TopLeftBlueCheckBox         matlab.ui.control.CheckBox
        TopRightBlueCheckBox        matlab.ui.control.CheckBox
        BotLeftBlueCheckBox         matlab.ui.control.CheckBox
        BotRightBlueCheckBox        matlab.ui.control.CheckBox
        ValueSpinnerLabel           matlab.ui.control.Label
        BlueValueSpinner            matlab.ui.control.Spinner
        FocusPanel                  matlab.ui.container.Panel
        UpButton_2                  matlab.ui.control.Button
        DownButton_2                matlab.ui.control.Button
        AutoButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        objects % Container for serial ports
        Ymm2step = -200 % number of steps needed to move Y stage 1 mm
        Xmm2step = -40 % number of steps needed to move Y stage 1 mm
        htop
        SaveDir % Save Directory
        output % Responce of last sent command
        lastSaveName % string of the last Saved file name
        SampleCount % count of files using using current save name
    end
    
    methods (Access = private)
        
        function [] = calExtents(app)
            [forward, backward] = serialCom.findextens(app.objects.Xmotor);
            app.objects.Cal.LRextents = [forward, backward];
            serialCom.returnHome(app.objects.Xmotor, app.objects.Cal.LRextents(1),app.objects.Cal.LRextents(2));
            [top, bottom] = serialCom.findextens(app.objects.Ymotor);
            app.objects.Cal.TBextents = [top, bottom];
            serialCom.returnHome(app.objects.Ymotor, app.objects.Cal.TBextents(1),app.objects.Cal.TBextents(2));
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.objects = PlatformObjects;
            catch err
                pattern = regexptranslate('wildcard','no*Select');
                loc = regexp(err.identifier,pattern, 'once');
                if ~isempty(loc)
                    if ~isempty(regexp(err.identifier,'Port', 'once'))
                        msg = 'No Port Selected:';
                    else
                        msg = 'No Device Selected:';
                    end
                    selection = uiconfirm(app.UIFigure,{msg,'Would you like to close ImagingPlatform?'},'NothingSelected',...
                        'Options',{'Close','Try Again'},...
                        'DefaultOption',1,'CancelOption',1,...
                        'Icon','error');
                    if strcmpi(selection,'close')
                        delete(app)
                    else
                        app.startupFcn();
                    end
                    return
                else
                    rethrow(err);
                end
            end
            h = preview(app.objects.VidObj);
            app.htop=ancestor(h,{'figure'},'toplevel');

            %             calExtents(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            if isempty(app.SaveDirEditField.Value)
                userProfile = getenv('USERPROFILE');
                app.SaveDir = uigetdir(userProfile,'Select Where to Save Images');
            else
                app.SaveDir = uigetdir(app.SaveDir,'Select Where to Save Images');
            end
            if ~(app.SaveDir == 0)
                app.SaveDirEditField.Value = app.SaveDir;
                app.SaveImageButton.Enable = 'on';
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            dist = abs(app.Ymm2step*app.DistancemmEditField.Value);
            if app.InvertTopBottomCheckBox.Value == 0
                serialCom.stepMove(app.objects.Ymotor ,-dist);
            else
                serialCom.stepMove(app.objects.Ymotor ,dist);
            end
            app.output = serialCom.writeToSerial(app.objects.Ymotor,'D');
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            dist = abs(app.Xmm2step*app.DistancemmEditField.Value);
            if app.InvertLeftRightCheckBox.Value == 0
                serialCom.stepMove(app.objects.Xmotor ,dist);
            else
                serialCom.stepMove(app.objects.Xmotor ,-dist);
            end
            app.output = serialCom.writeToSerial(app.objects.Xmotor,'D');
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            dist = abs(app.Xmm2step*app.DistancemmEditField.Value);
            if app.InvertLeftRightCheckBox.Value == 0
                serialCom.stepMove(app.objects.Xmotor ,-dist);
            else
                serialCom.stepMove(app.objects.Xmotor ,dist);
            end
            app.output = serialCom.writeToSerial(app.objects.Xmotor,'D');
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            dist = abs(app.Ymm2step*app.DistancemmEditField.Value);
            if app.InvertTopBottomCheckBox.Value == 0
                serialCom.stepMove(app.objects.Ymotor ,dist);
            else
                serialCom.stepMove(app.objects.Ymotor ,-dist);
            end
            app.output = serialCom.writeToSerial(app.objects.Ymotor,'D');
        end

        % Button pushed function: OpenPreviewWindowButton
        function OpenPreviewWindowButtonPushed(app, event)
            if ~ishandle(app.htop)
                h = preview(app.objects.VidObj);
                app.htop=ancestor(h,{'figure'},'toplevel');
            end
        end

        % Button pushed function: RecenterPlatformButton
        function RecenterPlatformButtonPushed(app, event)
            serialCom.returnHome(app.objects.Xmotor);
            serialCom.returnHome(app.objects.Ymotor);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            
            saveName = app.FileNameEditField.Value;
            saveExt = app.ImageFormatDropDown.Value;
            exp ={  '(?i:\[DATE\])';...
                    '(?i:\[TIME\])';...
                    '(?i:\[(NUM((B)?(ER)?)?|#)\])'};
            rep = { datestr(now,'mmmddyy');...
                    datestr(now,'HHMMSS');...
                    '_(1)'};
            
            regexprep(saveName,exp,rep)
            
            exp ='(?i:\[(NUM(B)?(ER)?|#)\])';
            sufx = ' (1)';
            
            saveLoc = fullfile(app.SaveDir, [saveName, saveExt(2:end)]);
            saveLoc = nextname(saveLoc,sufx);
            file=java.io.File(saveLoc);
            if ~file.exists()
                try
                    imwrite(app.objects.CurImg, saveLoc);
                catch err
                    errordlg('An error occured. Please unplug and plug back in camera and restart program');
                end
            else
                i = 1;
                while file.exists()
                    saveLoc = fullfile(app.SaveDir, [saveName,'(',i,')', saveExt(2:end)]);
                    file=java.io.File(saveLoc);
                    i=i+1;
                end
                imwrite(app.objects.CurImg, saveLoc);
            end
            
        end

        % Button pushed function: SetBlueLEDsButton
        function SetBlueLEDsButtonPushed(app, event)
            val3 = app.TopBlueCheckBox.Value; % val 3
            val4 = app.LeftBlueCheckBox.Value; % val 4
            val2 = app.BottomBlueCheckBox.Value; % val 2
            val1 = app.RightBlueCheckBox.Value;% val 1
            % note these may need to change
            val5 = app.TopLeftBlueCheckBox.Value; % val 5
            val6 = app.TopRightBlueCheckBox.Value; % val 6
            val7 = app.BotLeftBlueCheckBox.Value; % val 7
            val8 = app.BotRightBlueCheckBox.Value;% val 8
            
            decvec = bi2de([val1,val2,val3,val4,val5,val6,val7,val8]);
            val=ceil((255/100)*app.BlueIntensityKnob.Value);
          
            fmt=num2str(3,'%%0%dg');
            decvec=sprintf(fmt,decvec);
            val =sprintf(fmt,val);
            
            serialCom.writeToSerial(app.objects.Ymotor,'LB000:000');
            serialCom.writeToSerial(app.objects.Ymotor,['LB',decvec,':',val]);
        end

        % Button pushed function: SetWhiteLEDsButton
        function SetWhiteLEDsButtonPushed(app, event)
            val3 = app.TopBlueCheckBox.Value; % val 3
            val4 = app.LeftBlueCheckBox.Value; % val 4
            val2 = app.BottomBlueCheckBox.Value; % val 2
            val1 = app.RightBlueCheckBox.Value;% val 1
            % note these may need to change
            val5 = app.TopLeftBlueCheckBox.Value; % val 5
            val6 = app.TopRightBlueCheckBox.Value; % val 6
            val7 = app.BotLeftBlueCheckBox.Value; % val 7
            val8 = app.BotRightBlueCheckBox.Value;% val 8
            
            decvec = bi2de([val1,val2,val3,val4,val5,val6,val7,val8]);
            val=ceil((255/100)*app.WhiteIntensityKnob.Value);
            
            fmt=num2str(3,'%%0%dg');
            decvec=sprintf(fmt,decvec);
            val =sprintf(fmt,val);
            
            serialCom.writeToSerial(app.objects.Ymotor,'LA000:000');
            serialCom.writeToSerial(app.objects.Ymotor,['LA',decvec,':',val]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ishandle(app.htop)
                close(app.htop);
            end
            try
                serialCom.writeToSerial(app.objects.Ymotor,'LA000:000');
                serialCom.writeToSerial(app.objects.Ymotor,'LB000:000');
            catch
            end
            serialCom.endAllSerial();
            delete(app);
            %             serialCom.endAllSerial();
        end

        % Value changed function: WhiteIntensityKnob
        function WhiteIntensityKnobValueChanged(app, event)
            value = app.WhiteIntensityKnob.Value;
            app.WhiteValueSpinner.Value = value;
        end

        % Callback function
        function WhiteValueEditFieldValueChanged(app, event)
            value = app.WhiteValueEditField.Value;
            app.WhiteIntensityKnob.Value= value;
        end

        % Value changed function: BlueIntensityKnob
        function BlueIntensityKnobValueChanged(app, event)
            value = app.BlueIntensityKnob.Value;
            app.BlueValueSpinner.Value = value;
        end

        % Callback function
        function BlueValueEditFieldValueChanged(app, event)
            value = app.BlueValueEditField.Value;
            app.BlueIntensityKnob.Value = value;
        end

        % Menu selected function: COMResetMenu
        function COMResetMenuSelected(app, event)
            imaqreset;
            serialCom.endAllSerial;
            app.startupFcn();
        end

        % Menu selected function: COMReinitMenu
        function COMReinitMenuSelected(app, event)
            
        end

        % Value changed function: BlueValueSpinner
        function BlueValueSpinnerValueChanged(app, event)
            value = app.BlueValueSpinner.Value;
            app.BlueIntensityKnob.Value = value;
        end

        % Value changed function: WhiteValueSpinner
        function WhiteValueSpinnerValueChanged(app, event)
            value = app.WhiteValueSpinner.Value;
            app.WhiteIntensityKnob.Value= value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.COMResetMenu = uimenu(app.Menu);
            app.COMResetMenu.MenuSelectedFcn = createCallbackFcn(app, @COMResetMenuSelected, true);
            app.COMResetMenu.Text = 'COM Reset';
            app.COMReinitMenu = uimenu(app.Menu);
            app.COMReinitMenu.MenuSelectedFcn = createCallbackFcn(app, @COMReinitMenuSelected, true);
            app.COMReinitMenu.Text = 'COM Reinit';
            app.XYStagePanel = uipanel(app.UIFigure);
            app.XYStagePanel.Title = 'XY Stage';
            app.UpButton = uibutton(app.XYStagePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.RightButton = uibutton(app.XYStagePanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.XYStagePanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.DownButton = uibutton(app.XYStagePanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.DistancemmEditFieldLabel = uilabel(app.XYStagePanel);
            app.DistancemmEditFieldLabel.Text = 'Distance (mm)';
            app.DistancemmEditField = uieditfield(app.XYStagePanel, 'numeric');
            app.DistancemmEditField.Limits = [0 Inf];
            app.DistancemmEditField.Value = 1;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.SaveImageButton = uibutton(app.ImageTab, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.SaveDirEditFieldLabel = uilabel(app.ImageTab);
            app.SaveDirEditFieldLabel.Text = 'Save Dir';
            app.SaveDirEditField = uieditfield(app.ImageTab, 'text');
            app.SaveDirEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.ImageTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileNameEditFieldLabel = uilabel(app.ImageTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ImageTab, 'text');
            app.FileNameEditField.Value = 'Image1';
            app.ImageFormatDropDownLabel = uilabel(app.ImageTab);
            app.ImageFormatDropDownLabel.Text = 'Image Format';
            app.ImageFormatDropDown = uidropdown(app.ImageTab);
            app.ImageFormatDropDown.Items = {'*.bmp', '*.gif', '*.jpeg', '*.png', '*.ras', '*.tiff'};
            app.ImageFormatDropDown.Value = '*.tiff';
            app.TextArea = uitextarea(app.ImageTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'''[DATE]'' to insert current Date'; '''[TIME]'' to insert current Time'; '''[NUM]'', ''[NUMBER]'', or ''[#]'' to insert sample count'};
            app.VideoTab = uitab(app.TabGroup);
            app.VideoTab.Title = 'Video';
            app.SaveVideoButton = uibutton(app.VideoTab, 'push');
            app.SaveVideoButton.Enable = 'off';
            app.SaveVideoButton.Text = 'Save Video';
            app.BrowseButton_2 = uibutton(app.VideoTab, 'push');
            app.BrowseButton_2.Text = 'Browse';
            app.TextArea_2 = uitextarea(app.VideoTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'''[DATE]'' to insert current Date'; '''[TIME]'' to insert current Time'; '''[NUM]'', ''[NUMBER]'', or ''[#]'' to insert sample count'};
            app.SaveDirEditField_2Label = uilabel(app.VideoTab);
            app.SaveDirEditField_2Label.Text = 'Save Dir';
            app.SaveDirEditField_2 = uieditfield(app.VideoTab, 'text');
            app.SaveDirEditField_2.Editable = 'off';
            app.FileNameEditField_2Label = uilabel(app.VideoTab);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.VideoTab, 'text');
            app.FileNameEditField_2.Value = 'Image1';
            app.ImageFormatDropDown_2Label = uilabel(app.VideoTab);
            app.ImageFormatDropDown_2Label.Text = 'Image Format';
            app.ImageFormatDropDown_2 = uidropdown(app.VideoTab);
            app.ImageFormatDropDown_2.Items = {'*.bmp', '*.gif', '*.jpeg', '*.png', '*.ras', '*.tiff'};
            app.ImageFormatDropDown_2.Value = '*.tiff';
            app.MotorTab = uitab(app.TabGroup);
            app.MotorTab.Title = 'Motor';
            app.RecenterPlatformButton = uibutton(app.MotorTab, 'push');
            app.RecenterPlatformButton.ButtonPushedFcn = createCallbackFcn(app, @RecenterPlatformButtonPushed, true);
            app.RecenterPlatformButton.Text = 'Recenter Platform';
            app.InvertTopBottomCheckBox = uicheckbox(app.MotorTab);
            app.InvertTopBottomCheckBox.Text = 'Invert Top/Bottom';
            app.InvertLeftRightCheckBox = uicheckbox(app.MotorTab);
            app.InvertLeftRightCheckBox.Text = 'Invert Left/Right';
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.OpenPreviewWindowButton = uibutton(app.PreviewTab, 'push');
            app.OpenPreviewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPreviewWindowButtonPushed, true);
            app.OpenPreviewWindowButton.Text = 'Open Preview Window';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.WhiteLEDsTab = uitab(app.TabGroup2);
            app.WhiteLEDsTab.Title = 'White LEDs';
            app.SetWhiteLEDsButton = uibutton(app.WhiteLEDsTab, 'push');
            app.SetWhiteLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SetWhiteLEDsButtonPushed, true);
            app.SetWhiteLEDsButton.Text = 'Set White LEDs';
            app.IntensityKnobLabel_2 = uilabel(app.WhiteLEDsTab);
            app.IntensityKnobLabel_2.Text = 'Intensity';
            app.WhiteIntensityKnob = uiknob(app.WhiteLEDsTab, 'continuous');
            app.WhiteIntensityKnob.ValueChangedFcn = createCallbackFcn(app, @WhiteIntensityKnobValueChanged, true);
            app.WhiteIntensityKnob.Value = 100;
            app.ValueSpinnerLabel_2 = uilabel(app.WhiteLEDsTab);
            app.ValueSpinnerLabel_2.Text = 'Value';
            app.WhiteValueSpinner = uispinner(app.WhiteLEDsTab);
            app.WhiteValueSpinner.Limits = [0 100];
            app.WhiteValueSpinner.RoundFractionalValues = 'on';
            app.WhiteValueSpinner.ValueChangedFcn = createCallbackFcn(app, @WhiteValueSpinnerValueChanged, true);
            app.WhiteValueSpinner.Value = 100;
            app.BlueLEDsTab = uitab(app.TabGroup2);
            app.BlueLEDsTab.Title = 'Blue LEDs';
            app.IntensityKnobLabel = uilabel(app.BlueLEDsTab);
            app.IntensityKnobLabel.Text = 'Intensity';
            app.BlueIntensityKnob = uiknob(app.BlueLEDsTab, 'continuous');
            app.BlueIntensityKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.BlueIntensityKnob.ValueChangedFcn = createCallbackFcn(app, @BlueIntensityKnobValueChanged, true);
            app.TopBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.TopBlueCheckBox.Text = 'Top Blue';
            app.LeftBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.LeftBlueCheckBox.Text = 'Left Blue';
            app.BottomBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.BottomBlueCheckBox.Text = 'Bottom Blue';
            app.RightBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.RightBlueCheckBox.Text = 'Right Blue';
            app.SetBlueLEDsButton = uibutton(app.BlueLEDsTab, 'push');
            app.SetBlueLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SetBlueLEDsButtonPushed, true);
            app.SetBlueLEDsButton.Text = 'Set Blue LEDs';
            app.TopLeftBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.TopLeftBlueCheckBox.Text = 'Top/Left Blue';
            app.TopRightBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.TopRightBlueCheckBox.Text = 'Top/Right Blue';
            app.BotLeftBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.BotLeftBlueCheckBox.Text = 'Bot/Left Blue';
            app.BotRightBlueCheckBox = uicheckbox(app.BlueLEDsTab);
            app.BotRightBlueCheckBox.Text = 'Bot/Right Blue';
            app.ValueSpinnerLabel = uilabel(app.BlueLEDsTab);
            app.ValueSpinnerLabel.Text = 'Value';
            app.BlueValueSpinner = uispinner(app.BlueLEDsTab);
            app.BlueValueSpinner.Limits = [0 100];
            app.BlueValueSpinner.RoundFractionalValues = 'on';
            app.BlueValueSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueValueSpinnerValueChanged, true);
            app.FocusPanel = uipanel(app.UIFigure);
            app.FocusPanel.Title = 'Focus';
            app.UpButton_2 = uibutton(app.FocusPanel, 'push');
            app.UpButton_2.Text = 'Up';
            app.DownButton_2 = uibutton(app.FocusPanel, 'push');
            app.DownButton_2.Text = 'Down';
            app.AutoButton = uibutton(app.FocusPanel, 'push');
            app.AutoButton.Text = 'Auto';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImagingPlatform

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end